classdef v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BurningVelocityEditField       matlab.ui.control.NumericEditField
        BurningVelocityEditFieldLabel  matlab.ui.control.Label
        CalculateVelocityButton        matlab.ui.control.Button
        LPMEditField                   matlab.ui.control.NumericEditField
        LPMEditFieldLabel              matlab.ui.control.Label
        PremixedFlameBurningVelocityLabel  matlab.ui.control.Label
        WidthcmEditField               matlab.ui.control.NumericEditField
        WidthLabel                     matlab.ui.control.Label
        HeightcmEditField              matlab.ui.control.NumericEditField
        HeightLabel                    matlab.ui.control.Label
        RemoveNoiseButton              matlab.ui.control.Button
        ThresEditField                 matlab.ui.control.NumericEditField
        ThresEditFieldLabel            matlab.ui.control.Label
        BinaryButton                   matlab.ui.control.StateButton
        ThresholdSlider                matlab.ui.control.Slider
        ThresholdSliderLabel           matlab.ui.control.Label
        SharpButton                    matlab.ui.control.Button
        SmoothButton                   matlab.ui.control.Button
        ProcessButton                  matlab.ui.control.Button
        CalibrationPanel               matlab.ui.container.Panel
        CalibrateButton                matlab.ui.control.Button
        PlotCalibrationLineButton      matlab.ui.control.StateButton
        PixelsEditField                matlab.ui.control.NumericEditField
        PixelsEditFieldLabel           matlab.ui.control.Label
        LengthpxEditField              matlab.ui.control.NumericEditField
        LengthpxEditFieldLabel         matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        BrowseButton                   matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file = 0
        path
        clbr
        lpx = 1
        img
        nimg
        thimg
        ht = 0
        wd = 0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [app.file,app.path] = uigetfile({'*.png;*.jpg','Images (*.png,*.jpg)';'*.*', 'All Files (*.*)'});
            if isequal(app.file,0)
                return
            end
            cla(app.UIAxes,"reset");
            [app.img] = imread(fullfile(app.path,app.file));
            imshow(app.img,'parent',app.UIAxes);
            app.nimg = app.img;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes,"reset")
            app.file = 0;
            app.LengthEditField.Value = 0;
            app.PixelsEditField.Value = 0;
            app.LengthpxEditField.Value = 1;
            app.lpx = 1;
        end

        % Value changed function: PlotCalibrationLineButton
        function PlotCalibrationLineButtonValueChanged(app, event)
            if ~isempty(app.img)
                value = app.PlotCalibrationLineButton.Value;
                if value == 1
                    app.clbr = drawline("Deletable",true,"Visible",1,"Parent",app.UIAxes);
                elseif value == 0
                    app.clbr.Visible=0;
                end
            else
                app.PlotCalibrationLineButton.Value = 0;
            end
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            if app.PlotCalibrationLineButton.Value == 1
                pos = app.clbr.Position;
                delete(app.clbr);
                diffPos = diff(pos);
                app.PixelsEditField.Value = hypot(diffPos(1),diffPos(2));
                if app.LengthEditField.Value>0
                    app.LengthpxEditField.Value = app.LengthEditField.Value/app.PixelsEditField.Value;
                else
                    app.LengthpxEditField.Value = 1;
                end
                app.lpx = app.LengthpxEditField.Value;
                app.PlotCalibrationLineButton.Value = 0;
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            %Flame Detection and Height Calculation
            maskedRgbImage_ = bsxfun(@times, app.img, cast(app.thimg, 'like', app.img));
            props = regionprops(app.thimg, 'BoundingBox');
            boundingBox = [props.BoundingBox];
            pos = boundingBox;
            imshow(maskedRgbImage_,'parent',app.UIAxes);
            str = sprintf('Height = %0.02f cm',boundingBox(4)*app.lpx);
            app.ht = boundingBox(4)*app.lpx;
            app.wd = boundingBox(3)*app.lpx;
            app.HeightcmEditField.Value = boundingBox(4)*app.lpx;
            app.WidthcmEditField.Value = boundingBox(3)*app.lpx;
            rectangle('Position', boundingBox, 'LineWidth', 1, 'EdgeColor', 'r','Parent',app.UIAxes)
            text(pos(1) + pos(3)/2,pos(2) + pos(4),str,'VerticalAlignment','top','HorizontalAlignment','center','Color', 'r','Parent',app.UIAxes);
            close(gcf)
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            app.nimg = imgaussfilt(app.img,6);
            imshow(app.nimg,'parent',app.UIAxes);
        end

        % Button pushed function: SharpButton
        function SharpButtonPushed(app, event)
            app.nimg = imsharpen(app.nimg,'Radius',10,'Amount',1);
            imshow(app.nimg,'parent',app.UIAxes);
        end

        % Value changed function: BinaryButton
        function BinaryButtonValueChanged(app, event)
            value = app.BinaryButton.Value;
            if value == 1
                if ~isempty(app.img)
                    app.thimg = im2bw(app.nimg,app.ThresEditField.Value);
                    imshow(app.thimg,'parent',app.UIAxes);
                else
                    app.BinaryButton.Value = 0;
                end
            end
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ThresEditField.Value = changingValue;
            if app.BinaryButton.Value == 1
                if ~isempty(app.img)
                    app.thimg = im2bw(app.nimg,app.ThresEditField.Value);
                    imshow(app.thimg,'Parent',app.UIAxes);
                else
                    app.BinaryButton.Value = 0;
                end
            end

        end

        % Value changed function: ThresEditField
        function ThresEditFieldValueChanged(app, event)
            value = app.ThresEditField.Value;
            if value<0
                app.ThresEditField.Value = 0;
            elseif value>1
                app.ThresEditField.Value = 1;
            end
            app.ThresholdSlider.Value = app.ThresEditField.Value;
        end

        % Button pushed function: RemoveNoiseButton
        function RemoveNoiseButtonPushed(app, event)
            app.thimg = bwareafilt(app.thimg, 1);
            imshow(app.thimg,'parent',app.UIAxes);
        end

        % Button pushed function: CalculateVelocityButton
        function CalculateVelocityButtonPushed(app, event)
            if app.wd ~= 0 && app.ht ~= 0
                app.BurningVelocityEditField.Value = app.LPMEditField.Value*(app.wd/2)/(sqrt(app.wd*app.wd/4 + app.ht*app.ht));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CalibrationPanel = uipanel(app.UIFigure);
            app.CalibrationPanel.Title = 'Calibration';
            app.LengthEditFieldLabel = uilabel(app.CalibrationPanel);
            app.LengthEditFieldLabel.Text = 'Length:';
            app.LengthEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.LengthpxEditFieldLabel = uilabel(app.CalibrationPanel);
            app.LengthpxEditFieldLabel.Text = 'Length/px:';
            app.LengthpxEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.LengthpxEditField.Editable = 'off';
            app.LengthpxEditField.Value = 1;
            app.PixelsEditFieldLabel = uilabel(app.CalibrationPanel);
            app.PixelsEditFieldLabel.Text = 'Pixels:';
            app.PixelsEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.PixelsEditField.Editable = 'off';
            app.PlotCalibrationLineButton = uibutton(app.CalibrationPanel, 'state');
            app.PlotCalibrationLineButton.ValueChangedFcn = createCallbackFcn(app, @PlotCalibrationLineButtonValueChanged, true);
            app.PlotCalibrationLineButton.Text = 'Plot Calibration Line';
            app.CalibrateButton = uibutton(app.CalibrationPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.SmoothButton = uibutton(app.UIFigure, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.SharpButton = uibutton(app.UIFigure, 'push');
            app.SharpButton.ButtonPushedFcn = createCallbackFcn(app, @SharpButtonPushed, true);
            app.SharpButton.Text = 'Sharp';
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThresholdSlider.Value = 0.5;
            app.BinaryButton = uibutton(app.UIFigure, 'state');
            app.BinaryButton.ValueChangedFcn = createCallbackFcn(app, @BinaryButtonValueChanged, true);
            app.BinaryButton.Text = 'Binary';
            app.ThresEditFieldLabel = uilabel(app.UIFigure);
            app.ThresEditFieldLabel.Text = 'Thres';
            app.ThresEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresEditField.Limits = [0 1];
            app.ThresEditField.ValueDisplayFormat = '%.2f';
            app.ThresEditField.ValueChangedFcn = createCallbackFcn(app, @ThresEditFieldValueChanged, true);
            app.ThresEditField.Value = 0.5;
            app.RemoveNoiseButton = uibutton(app.UIFigure, 'push');
            app.RemoveNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNoiseButtonPushed, true);
            app.RemoveNoiseButton.Text = 'Remove Noise';
            app.HeightLabel = uilabel(app.UIFigure);
            app.HeightLabel.Text = 'Height(cm) :';
            app.HeightcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightcmEditField.Editable = 'off';
            app.WidthLabel = uilabel(app.UIFigure);
            app.WidthLabel.Text = 'Width(cm) :';
            app.WidthcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PremixedFlameBurningVelocityLabel = uilabel(app.UIFigure);
            app.PremixedFlameBurningVelocityLabel.Text = 'Premixed Flame Burning Velocity : ';
            app.LPMEditFieldLabel = uilabel(app.UIFigure);
            app.LPMEditFieldLabel.Text = 'LPM : ';
            app.LPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateVelocityButton = uibutton(app.UIFigure, 'push');
            app.CalculateVelocityButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateVelocityButtonPushed, true);
            app.CalculateVelocityButton.Text = 'Calculate Velocity';
            app.BurningVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.BurningVelocityEditFieldLabel.Text = 'Burning Velocity : ';
            app.BurningVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end